classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        BinaryzujButton             matlab.ui.control.Button
        RozpoznajliteryButton       matlab.ui.control.Button
        WynikEditField              matlab.ui.control.EditField
        WynikEditFieldLabel         matlab.ui.control.Label
        WczytajobrazButton          matlab.ui.control.Button
        KonwertujdoarabskichButton  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        imageFile;
        dict = ['C'; 'D';'I';'L'; 'M';'V';'X'];
        nn = load ('ocr_final_8_new.mat'); % siec neuronowa
         fun = {@AO5RBlairBliss, @AO5RCircularityL, @AO5RCircularityS, @AO5RShape, @AO5RDanielsson, @AO5RFeret, @AO5RHaralick, @AO5RMalinowska};
        wsp_train = {@wspolczynniki_train};
        bim; % binarny obraz
    end

    methods (Access = private)
        
        %binaryzacja
        function [gray_im,bim] = bin_im(app)
            app.bim = ~imbinarize(app.imageFile,'adaptive','ForegroundPolarity','dark','Sensitivity',0.1);
            app.bim = app.bim(:,:,1);
            app.bim = imclose(app.bim,strel('disk',10));
            app.bim = imopen(app.bim,ones(5));
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WczytajobrazButton
        function loadImage(app, event)
            [filename, filepath] = uigetfile({'*.*';'*.jpg';'*.png';'*.bmp'}, 'Choose file to load');
            figure(app.UIFigure)
            FullFileName = [filepath filename];
          
            %load image if file selected
            if ~isequal(filename,0)
                app.imageFile = double(imread(FullFileName))/255;
                imshow(app.imageFile,'parent',app.UIAxes);
                app.WynikEditField.Value = '';

        end
        end

        % Button pushed function: KonwertujdoarabskichButton
        function converToArabic(app, event)
            app.bim = ~imbinarize(app.imageFile,'adaptive','ForegroundPolarity','dark','Sensitivity',0.1);
            app.bim = app.bim(:,:,1);
            app.bim = imclose(app.bim,strel('disk',10));
            app.bim = imopen(app.bim,ones(5));

            test=app.bim;
            test = test(:,:,1);
            test = imopen(test,ones(2));
            a = regionprops(test,'Image');
            wtest = zeros(length(a), length(app.fun));
            for i = 1:length(a)
                for j = 1:length(app.fun)
                    binary_image = a(i).Image;
                    binary_image = padarray(binary_image,0);
                    binary_image = imresize(binary_image, [300, 300]);
                    wtest(i,j) =  app.fun{j}(binary_image);
                end
            end
            out1 = app.nn.nn(wtest');
            result = '';
            for i = 1:1:size(out1,2)
                result = strcat(result,string(app.dict(find(out1(:,i) == max(out1(:,i))))));
            end

            s=result;
            decimal = [1000, 500, 100, 50, 10, 5, 1];
    roman = ['M', 'D', 'C', 'L', 'X', 'V', 'I'];
    tokens = arrayfun(@(x) decimal(find(roman==x,1)), char(s));
    num = tokens(end);
    for idx = 1:numel(tokens)-1
        val = tokens(idx);
        ridx = find(tokens(idx+1:end) ~= val, 1);
        if ~isempty(ridx) && tokens(ridx + idx) > val
            num = num - val;
        else
            num = num + val;
        end
    end
    app.WynikEditField.Value =int2str(num);
    
        end

        % Button pushed function: RozpoznajliteryButton
        function RozpoznajliteryButtonPushed(app, event)
            app.bim = ~imbinarize(app.imageFile,'adaptive','ForegroundPolarity','dark','Sensitivity',0.1);
            app.bim = app.bim(:,:,1);
            app.bim = imclose(app.bim,strel('disk',10));
            app.bim = imopen(app.bim,ones(5));

            test=app.bim;
            test = test(:,:,1);
            test = imopen(test,ones(2));
            a = regionprops(test,'Image');
            wtest = zeros(length(a), length(app.fun));

             for i = 1:length(a)
                for j = 1:length(app.fun)
                    binary_image = a(i).Image;
                    binary_image = padarray(binary_image,0);
                    binary_image = imresize(binary_image, [300, 300]);
                    wtest(i,j) =  app.fun{j}(binary_image);
                end
            end
            out1 = app.nn.nn(wtest');
            result = '';
            for i = 1:1:size(out1,2)
                result = strcat(result,string(app.dict(find(out1(:,i) == max(out1(:,i))))));
            end
            app.WynikEditField.Value = result;


        end

        % Button pushed function: BinaryzujButton
        function BinaryzujButtonPushed(app, event)
            bin_im(app);
            imshow(app.bim,'parent',app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.KonwertujdoarabskichButton = uibutton(app.UIFigure, 'push');
            app.KonwertujdoarabskichButton.ButtonPushedFcn = createCallbackFcn(app, @converToArabic, true);
            app.KonwertujdoarabskichButton.Text = 'Konwertuj do arabskich';
            app.WczytajobrazButton = uibutton(app.UIFigure, 'push');
            app.WczytajobrazButton.ButtonPushedFcn = createCallbackFcn(app, @loadImage, true);
            app.WczytajobrazButton.Text = 'Wczytaj obraz';
            app.WynikEditFieldLabel = uilabel(app.UIFigure);
            app.WynikEditFieldLabel.Text = 'Wynik:';
            app.WynikEditField = uieditfield(app.UIFigure, 'text');
            app.RozpoznajliteryButton = uibutton(app.UIFigure, 'push');
            app.RozpoznajliteryButton.ButtonPushedFcn = createCallbackFcn(app, @RozpoznajliteryButtonPushed, true);
            app.RozpoznajliteryButton.Text = 'Rozpoznaj litery';
            app.BinaryzujButton = uibutton(app.UIFigure, 'push');
            app.BinaryzujButton.ButtonPushedFcn = createCallbackFcn(app, @BinaryzujButtonPushed, true);
            app.BinaryzujButton.Text = 'Binaryzuj';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end